classdef GUIv2 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                   matlab.ui.Figure
        Switch_2                   matlab.ui.control.Switch
        JuiceButton                matlab.ui.control.Button
        JointAngleButton           matlab.ui.control.Button
        JointAngleEditField        matlab.ui.control.NumericEditField
        JointAngleEditFieldLabel   matlab.ui.control.Label
        JointNumberEditField       matlab.ui.control.NumericEditField
        JointNumberEditFieldLabel  matlab.ui.control.Label
        CartesianButton            matlab.ui.control.Button
        ZEditField                 matlab.ui.control.NumericEditField
        ZEditFieldLabel            matlab.ui.control.Label
        YEditField                 matlab.ui.control.NumericEditField
        YEditFieldLabel            matlab.ui.control.Label
        XEditField                 matlab.ui.control.NumericEditField
        XEditFieldLabel            matlab.ui.control.Label
        Switch                     matlab.ui.control.Switch
        Lamp                       matlab.ui.control.Lamp
        RESUMEButton               matlab.ui.control.Button
        ESTOPButton                matlab.ui.control.Button
    end

    
    properties (Access = private)
        eStopActive = false;    % set initial estop state
        resumeCount = 0;        % inital press count

        robot = DobotMagician;

        zpos = 0;
        ypos = 0;
        xpos = 0;

        jointno = 0;
        jointangle = 0;

        DOF = 5;

    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: ESTOPButton
        function ESTOPButtonPushed(app, event)
            if app.eStopActive

                %resume logic
                app.resumeCount = app.resumeCount + 1;
                if app.resumeCount == 2
                    app.eStopActive = false;
                    app.resumeCount = 0;
                    app.Lamp.Color = "green";
                end
            else
                %E stop logic
                app.eStopActive = true;
                app.Lamp.Color = "red";

            end  
        end

        % Button pushed function: RESUMEButton
        function RESUMEButtonPushed(app, event)
            if app.eStopActive
                app.resumeCount = app.resumeCount + 1;
                if app.resumeCount == 2
                    app.eStopActive = false;
                    app.resumeCount = 0;
                    app.Lamp.Color = "green";
                end
            end
        end

        % Value changed function: Switch
        function SwitchValueChanged(app, event)
            value = app.Switch.Value;

            switch value
                case 'Teach'
                    % app.Lamp.Color = 'green';
                    switch app.Switch.Value
                        case 'Dobot'
                            clf;
                            app.robot = DobotMagician;
                        case 'Kinova'
                            clf;
                            app.robot = KinovaLink6;
                    end

                case 'Controller'
                    % app.Lamp.Color = 'red';
                    
                    id = 1; % Note: may need to be changed if multiple joysticks present
                    joy = vrjoystick(id);
                    caps(joy) % display joystick information

                    switch app.Switch_2.Value
                        case 'Dobot'
                            clf;
                            app.robot = DobotMagician;
                            app.DOF = 5;

                            q = zeros(1, app.DOF);  % Set initial robot configuration 'q'

                    HF = figure(1); % Initialise figure to display robot

                    app.robot.model.delay = 0.001;    % Set smaller delay when animating

                    duration = 300;  % Set duration of the simulation (seconds)
                    dt = 0.1;      % Set time step for simulation (seconds)
                    
                    n = 0;  % Initialise step count to zero 
                    tic;    % recording simulation start time
                    while( toc < duration)
                    
                        n=n+1; % increment step count
                    
                        % read joystick
                        [axes, buttons, povs] = read(joy);
                    
                        % -------------------------------------------------------------
                        % YOUR CODE GOES HERE
                        % 1 - turn joystick input into an end-effector velocity command
                        Kv = 0.2; % linear velocity gain
                        Kw = 1.0; % angular velocity gain
                    
                        vx = Kv*axes(2);
                        vy = Kv*axes(1);
                        vz = Kv*(buttons(5)-buttons(6));
                    
                        wx = Kw*axes(5);
                        wy = Kw*axes(4);
                        wz = Kw*axes(3);
                    
                        dx = [vx;vy;vz;wx;wy;wz]; % combined velocity vector
                    
                        % 2 - use J inverse to calculate joint velocity
                        J = app.robot.model.jacob0(q);
                        dq = pinv(J)*dx;
                    
                        % 3 - apply joint velocity to step robot joint angles 
                        q = q + dq'*dt;
                    
                        % -------------------------------------------------------------
                    
                        % Update plot
                        app.robot.model.animate(q);  


                        % wait until loop time elapsed
                        if (toc > dt*n)
                            warning('Loop %i took too much time - consider increating dt',n);
                        end
                        while (toc < dt*n); % wait until loop time (dt) has elapsed 
                        end

                    end

                        case 'Kinova'
                            clf;
                            app.robot = KinovaLink6;
                            app.DOF = 6;

                            q = zeros(1, 6);  % Set initial robot configuration 'q'

                    HF = figure(1); % Initialise figure to display robot

                    app.robot.model.delay = 0.001;    % Set smaller delay when animating

                    duration = 300;  % Set duration of the simulation (seconds)
                    dt = 0.1;      % Set time step for simulation (seconds)
                    
                    n = 0;  % Initialise step count to zero 
                    tic;    % recording simulation start time
                    while( toc < duration)
                    
                        n=n+1; % increment step count
                    
                        % read joystick
                        [axes, buttons, povs] = read(joy);
                    
                        % -------------------------------------------------------------
                        % YOUR CODE GOES HERE
                        % 1 - turn joystick input into an end-effector velocity command
                        Kv = 0.2; % linear velocity gain
                        Kw = 1.0; % angular velocity gain
                    
                        vx = Kv*axes(2);
                        vy = Kv*axes(1);
                        vz = Kv*(buttons(5)-buttons(6));
                    
                        wx = Kw*axes(5);
                        wy = Kw*axes(4);
                        wz = Kw*axes(3);
                    
                        dx = [vx;vy;vz;wx;wy;wz]; % combined velocity vector
                    
                        % 2 - use J inverse to calculate joint velocity
                        J = app.robot.model.jacob0(q);
                        dq = pinv(J)*dx;
                    
                        % 3 - apply joint velocity to step robot joint angles 
                        q = q + dq'*dt;
                    
                        % -------------------------------------------------------------
                    
                        % Update plot
                        app.robot.model.animate(q);  
                    
                        % wait until loop time elapsed
                        if (toc > dt*n)
                            warning('Loop %i took too much time - consider increating dt',n);
                        end
                        while (toc < dt*n); % wait until loop time (dt) has elapsed 
                        end

                    end
                    end
            end
            
        end

        % Value changed function: Switch_2
        function Switch_2ValueChanged(app, event)
            value = app.Switch_2.Value;
            
            switch value
                case 'Dobot'
                    clf;
                    app.robot = DobotMagician;
                case 'Kinova'
                    clf;
                    app.robot = KinovaLink6;
            end
        end

        % Value changed function: XEditField
        function XEditFieldValueChanged(app, event)
            app.xpos = app.XEditField.Value;
        end

        % Value changed function: YEditField
        function YEditFieldValueChanged(app, event)
            app.ypos = app.YEditField.Value;
        end

        % Value changed function: ZEditField
        function ZEditFieldValueChanged(app, event)
            app.zpos = app.ZEditField.Value;
        end

        % Button pushed function: CartesianButton
        function CartesianButtonPushed(app, event)
                q0 = app.robot.model.getpos;
                newq = app.robot.model.ikcon(transl(app.xpos,app.ypos,app.zpos));

                qtraj = jtraj(q0, newq, 25);

                for i = 1:size(qtraj, 1)
                    app.robot.model.animate(qtraj(i,:));
                    drawnow();
                    pause(0);
                end
        end

        % Button pushed function: JointAngleButton
        function JointAngleButtonPushed(app, event)
            q0 = app.robot.model.getpos;
            new_angle = app.jointangle;
            q0(app.jointno) = new_angle;

            newq = q0; % Copy the initial joint configuration
            newq(app.jointno) = new_angle; % Update the first joint to the desired angle

            qtraj = jtraj(q0, newq, 25);

            for i = 1:size(qtraj, 1)
                % Animate the robot with the interpolated joint angles
                app.robot.model.animate(qtraj(i,:));
                drawnow();
                pause(0.1); % You can adjust the pause duration for animation speed
            end
        end

        % Value changed function: JointNumberEditField
        function JointNumberEditFieldValueChanged(app, event)
            app.jointno = app.JointNumberEditField.Value;
            
        end

        % Value changed function: JointAngleEditField
        function JointAngleEditFieldValueChanged(app, event)
            app.jointangle = app.JointAngleEditField.Value;
            
        end

        % Button pushed function: JuiceButton
        function JuiceButtonPushed(app, event)


%%  SETUP
%Connect lemon to end affector
%collision detection

%singularity if time


%%  Positions
%Positions
lemonStartPos = [0.005, 0.5, 0.775];
lemonOffset = [0, -0.04, 0.008+0.035];
% lemonWidth = 0.035;
% lemonHeight = 0.102;

cuttingBoardPos = [1.160, 0.184, 0.775+0.07]+ lemonOffset;
juicerPos = [1.051, 0.85577, 0.775+0.036];
binPos = [1.33255, 0.85577, 0.775+0.3];

dobotPos = [1.6016, 0.1834, 0.775];
kinovaPos = [0.5434, 0.500, 0.775];


kinovaIdlePos = [1.16, -0.25, 0.8170];


numSteps = 50;

cutEndPos = cuttingBoardPos - [0, 0, 0.05];

cutStartPos = lemonOffset + cuttingBoardPos + [0, 0, 0.1]; 

%%  Object Placement
figure;
workspace = [-3, 3, -5, 2.5, -3, 3];
% axis equal
plotOptions.tile = false;

hold on;

dobot = DobotMagician();
kinova = KinovaLink6();
plotOptions.tile = false;


table = PlaceObject('tableV3.ply', [0 0 0]);

% lemon1 = PlaceObject('lemon1.ply', lemonStartPos);

[f, v, data] = plyread('lemon1.ply', 'tri');

vertexColors = [data.vertex.red, data.vertex.green, data.vertex.blue] / 255;
lemon1 = patch('Vertices', v, 'Faces', f, 'FaceVertexCData', vertexColors, 'FaceColor', 'interp', 'EdgeColor', 'none');
hold on;
originalLemonVertices = lemon1.Vertices - [0, 0, 0.07];

lemon1.Vertices = lemon1.Vertices + lemonStartPos;

%lemon2
[f, v, data] = plyread('lemon1.ply', 'tri');

vertexColors = [data.vertex.red, data.vertex.green, data.vertex.blue] / 255;
lemon2 = patch('Vertices', v, 'Faces', f, 'FaceVertexCData', vertexColors, 'FaceColor', 'interp', 'EdgeColor', 'none');
hold on;

lemon2.Vertices = lemon2.Vertices + lemonStartPos;

[f, v, data] = plyread('knifeHolder.ply', 'tri');

vertexColors = [data.vertex.red, data.vertex.green, data.vertex.blue] / 255;
knife = patch('Vertices', v, 'Faces', f, 'FaceVertexCData', vertexColors, 'FaceColor', 'interp', 'EdgeColor', 'none');
hold on;

originalKnifeVert = knife.Vertices;


% lemon1 = PlaceObject('lemon1.ply', cuttingBoardPos);
% lemon2 = PlaceObject('lemon2.ply', cuttingBoardPos);

camlight('headlight');

%Dobot Translation

dobot.model.base = transl(dobotPos) * trotz(pi);

dobot.model.animate(dobot.model.getpos());


%KinovaTranslation 

kinova.model.base = transl(kinovaPos);

kinova.model.animate(kinova.model.getpos());

view(3);

%%  MAIN

animateRobot(dobot, numSteps, cutStartPos, 1, knife, originalKnifeVert);


% animateRobot(kinova, numSteps, cuttingBoardPos, trotx(pi)); %move lemon to cutting board
RMRC(kinova, lemonStartPos+[0, 0, 1], pi, 1.5, 1, 1, 1);

RMRC(kinova, lemonStartPos+[0, 0, 0.07], pi, 1.5, 1, 1, 1);

RMRC(kinova, ((lemonStartPos+cuttingBoardPos)/2)+[0, -0.4, 0.2], pi, 1.5, originalLemonVertices, lemon1, lemon2);

RMRC(kinova, cuttingBoardPos, pi, 1.5, originalLemonVertices, lemon1, lemon2);

RMRC(kinova, kinovaIdlePos, pi, 1.5, 1, 1, 1);


% animateRobot(kinova, numSteps, kinovaIdlePos, trotx(pi)); %move out of the way

animateRobot(dobot, numSteps, cutEndPos, 1, knife, originalKnifeVert);%cut
animateRobot(dobot, numSteps, cutEndPos + [0.1, -0.15, 0.05], 1, knife, originalKnifeVert);%cut

animateRobot(dobot, numSteps, cutEndPos+[0.1, 0.15, 0.05], 1, knife, originalKnifeVert);%cut
animateRobot(dobot, numSteps, cutStartPos, 1, knife, originalKnifeVert);%startcutpos
%


% animateRobot(kinova, numSteps, cuttingBoardPos, trotx(pi));%pickup lemon off cuttingboard

RMRC(kinova, cuttingBoardPos, pi, 1.5, 1, 1, 1);
RMRC(kinova, juicerPos+[0, 0, 0.1], pi, 1.5, originalLemonVertices, lemon1, 1);

% animateRobot(kinova, numSteps, juicerPos+lemonOffset, trotx(pi));%move to juicer
% 
Juice(kinova, numSteps, 2*pi);
Juice(kinova, numSteps, -2*pi);
Juice(kinova, numSteps, 2*pi);
Juice(kinova, numSteps, 0);
% 
% animateRobot(kinova, numSteps, binPos, trotx(pi));%throw lemon in bin
RMRC(kinova, juicerPos+[0, 0, 0.5], pi, 1.5, originalLemonVertices, lemon1, 1);

RMRC(kinova, binPos, pi, 1.5, originalLemonVertices, lemon1, 1);

for j = 1:20
      lemon1.Vertices = lemon1.Vertices - [0, 0, 0.01];
      pause(0.2);
end

% animateRobot(kinova, numSteps, lemonStartPos, trotx(pi));% go to start
% 

RMRC(kinova, cuttingBoardPos, pi, 1.5, 1, 1, 1);
RMRC(kinova, juicerPos+[0, 0, 0.1], pi, 1.5, originalLemonVertices, lemon2, 1);

% animateRobot(kinova, numSteps, juicerPos+lemonOffset, trotx(pi));%move to juicer
% 
Juice(kinova, numSteps, 2*pi);
Juice(kinova, numSteps, -2*pi);
Juice(kinova, numSteps, 2*pi);
Juice(kinova, numSteps, 0);
% 
% animateRobot(kinova, numSteps, binPos, trotx(pi));%throw lemon in bin
RMRC(kinova, juicerPos+[0, 0, 0.5], pi, 1.5, originalLemonVertices, lemon2, 1);

RMRC(kinova, binPos, pi, 1.5, originalLemonVertices, lemon2, 1);

for j = 1:20
      lemon2.Vertices = lemon2.Vertices - [0, 0, 0.01];
      pause(0.2);
end


% finalJointStates = ikine(kinova.model.getpos();)

function RMRC(kinova, endPos, pitch, t, originalLemonVertices, lemon, lemon2)
beginPos = transl(kinova.model.fkine(kinova.model.getpos()));
beginX=beginPos(1);
beginY=beginPos(2);
beginZ=beginPos(3);
endX=endPos(1);
endY=endPos(2);
endZ=endPos(3);
estop = 0;


    %Set parameters for the simulation
    deltaT = 0.02;      % Control frequency
    steps = t/deltaT;   % No. of steps for simulation
    %deltaa = 2*pi/steps;

    epsilon = 0.1;      % Threshold value for manipulability/Damped Least Squares
    W = diag([1 1 1 0.1 0.1 0.1]);    % Weighting matrix for the velocity vector
    
    %Allocate array data
    m = zeros(steps,1);             % Array for Measure of Manipulability
    qMatrix = zeros(steps,6);       % Array for joint anglesR
    qdot = zeros(steps,6);          % Array for joint velocities
    theta = zeros(3,steps);         % Array for roll-pitch-yaw angles
    x = zeros(3,steps);             % Array for x-y-z trajectory
    
    %Set up trajectory, initial pose
    s = lspb(0,1,steps);                % Trapezoidal trajectory scalar
    for i=1:steps
        
        x(1,i) = (1-s(i))*beginX + s(i)*endX; % Points in x
        % if sina == 1
            x(2,i) = (1-s(i))*beginY + s(i)*endY; % Points in y
        % 
        % else
        %         x(2,i) = beginZ + -0.3*sin(i*5); % Points in z
        % 
        % end
            
            
        x(3,i) = (1-s(i))*beginZ + s(i)*endZ; % Points in z

        
        theta(1,i) = 0;                 % Roll angle 
        theta(2,i) = pitch;            % Pitch angle
        theta(3,i) = 0;                 % Yaw angle
    end
     
    qMatrix(1,:) = kinova.model.getpos();                                            % SET joint for first waypoint %
    
    %Track the trajectory with RMRC
    for i = 1:steps-1
        T = kinova.model.fkine(qMatrix(i,:)).T;                                           % Get forward transformation at current joint state
        deltaX = x(:,i+1) - T(1:3,4);                                         	% Get position error from next waypoint
        Rd = rpy2r(theta(1,i+1),theta(2,i+1),theta(3,i+1));                     % Get next RPY angles, convert to rotation matrix
        Ra = T(1:3,1:3);                                                        % Current end-effector rotation matrix
        Rdot = (1/deltaT)*(Rd - Ra);                                                % Calculate rotation matrix error
        S = Rdot*Ra';                                                           % Skew symmetric!
        linear_velocity = (1/deltaT)*deltaX;
        angular_velocity = [S(3,2);S(1,3);S(2,1)];                              % Check the structure of Skew Symmetric matrix!!
        xdot = W*[linear_velocity;angular_velocity];                          	% Calculate end-effector velocity to reach next waypoint.
        J = kinova.model.jacob0(qMatrix(i,:));                 % Get Jacobian at current joint state
        m(i) = sqrt(det(J*J'));


        if m(i) < epsilon  % If manipulability is less than given threshold
            lambda = (1 - m(i)/epsilon)*5E-2;
        else
            lambda = 0;
        end
        invJ = inv(J'*J + lambda *eye(6))*J';                                   % DLS Inverse
        qdot(i,:) = (invJ*xdot)';                                                % Solve the RMRC equation (you may need to transpose the         vector)
        for j = 1:6                                                             % Loop through joints 1 to 6
            if qMatrix(i,j) + deltaT*qdot(i,j) < kinova.model.qlim(j,1)                     % If next joint angle is lower than joint limit...
                qdot(i,j) = 0; % Stop the motor
            elseif qMatrix(i,j) + deltaT*qdot(i,j) > kinova.model.qlim(j,2)                 % If next joint angle is greater than joint limit ...
                qdot(i,j) = 0; % Stop the motor
            end
        end
        qMatrix(i+1,:) = qMatrix(i,:) + deltaT*qdot(i,:);                         	% Update next joint state based on joint velocities
    end
    
    test = 0;
    for i = 1:steps
        kinova.model.animate(qMatrix(i,:))
        if app.eStopActive == true
            test = 1;
        end
        while test == 1
            if app.RESUMEButton == true
                app.eStopActive = false;
                test = 0;
                continue;
            end
        end

        drawnow();
        if (lemon == 1)

        elseif lemon2 == 1
            lemon.Vertices = originalLemonVertices + transl(kinova.model.fkine(kinova.model.getpos()));
        else
            lemon2.Vertices = originalLemonVertices + transl(kinova.model.fkine(kinova.model.getpos()));
            lemon.Vertices = originalLemonVertices + transl(kinova.model.fkine(kinova.model.getpos()));

        end
    end

    disp('Kinova Joints');
    disp(qMatrix(end,:));
end

function animateRobot(robot, steps, position, angle, knife, originalKnifeVert)
    lemonPosikcon = robot.model.ikcon(transl(position)*angle);
    estop = 0;
    jointTrajectory = jtraj(robot.model.getpos(), lemonPosikcon, steps);
    test = 0;
    for j = 1:steps
        q = jointTrajectory(j, :); % Get joint configuration for the current step
        robot.model.animate(q); % Animate the robot
        drawnow();
        knife.Vertices =  originalKnifeVert + transl(robot.model.fkine(robot.model.getpos()));
        if app.eStopActive == true
            test = 1;
        end
        while test == 1
            if app.RESUMEButton == true
                app.eStopActive = false;
                test = 0;
                continue;
            end
        end


    end
    if length(q) == 5
        disp('Dobot Joints');
        disp(q);
    else
        disp('Kinova Joints');
        disp(q);
    end

end 

function Juice(kinova, steps, spinAngle)
    juiceAim = kinova.model.getpos();
estop = 0;
    juiceAim(6) = spinAngle;
        
    jointTrajectory = jtraj(kinova.model.getpos(), juiceAim, steps);
    test = 0;
    for j = 1:steps
        q = jointTrajectory(j, :); % Get joint configuration for the current step
        kinova.model.animate(q); % Animate the robot
        drawnow();
        if app.eStopActive == true
            test = 1;
        end
        while test == 1
            if app.RESUMEButton == true
                app.eStopActive = false;
                test = 0;
                continue;
            end
        end

    end
    if length(q) == 5
        disp('Dobot Joints');
        disp(q);
    else
        disp('Kinova Joints');
        disp(q);
    end

end 
clf;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.ESTOPButton = uibutton(app.UIFigure, 'push');
            app.ESTOPButton.ButtonPushedFcn = createCallbackFcn(app, @ESTOPButtonPushed, true);
            app.ESTOPButton.Text = 'E STOP';
            app.RESUMEButton = uibutton(app.UIFigure, 'push');
            app.RESUMEButton.ButtonPushedFcn = createCallbackFcn(app, @RESUMEButtonPushed, true);
            app.RESUMEButton.Text = 'RESUME';
            app.Lamp = uilamp(app.UIFigure);
            app.Switch = uiswitch(app.UIFigure, 'slider');
            app.Switch.Items = {'Teach', 'Controller'};
            app.Switch.ValueChangedFcn = createCallbackFcn(app, @SwitchValueChanged, true);
            app.Switch.Value = 'Teach';
            app.XEditFieldLabel = uilabel(app.UIFigure);
            app.XEditFieldLabel.Text = 'X';
            app.XEditField = uieditfield(app.UIFigure, 'numeric');
            app.XEditField.ValueChangedFcn = createCallbackFcn(app, @XEditFieldValueChanged, true);
            app.YEditFieldLabel = uilabel(app.UIFigure);
            app.YEditFieldLabel.Text = 'Y';
            app.YEditField = uieditfield(app.UIFigure, 'numeric');
            app.YEditField.ValueChangedFcn = createCallbackFcn(app, @YEditFieldValueChanged, true);
            app.ZEditFieldLabel = uilabel(app.UIFigure);
            app.ZEditFieldLabel.Text = 'Z';
            app.ZEditField = uieditfield(app.UIFigure, 'numeric');
            app.ZEditField.ValueChangedFcn = createCallbackFcn(app, @ZEditFieldValueChanged, true);
            app.CartesianButton = uibutton(app.UIFigure, 'push');
            app.CartesianButton.ButtonPushedFcn = createCallbackFcn(app, @CartesianButtonPushed, true);
            app.CartesianButton.Text = 'Cartesian';
            app.JointNumberEditFieldLabel = uilabel(app.UIFigure);
            app.JointNumberEditFieldLabel.Text = 'Joint Number';
            app.JointNumberEditField = uieditfield(app.UIFigure, 'numeric');
            app.JointNumberEditField.ValueChangedFcn = createCallbackFcn(app, @JointNumberEditFieldValueChanged, true);
            app.JointAngleEditFieldLabel = uilabel(app.UIFigure);
            app.JointAngleEditFieldLabel.Text = 'Joint Angle';
            app.JointAngleEditField = uieditfield(app.UIFigure, 'numeric');
            app.JointAngleEditField.ValueChangedFcn = createCallbackFcn(app, @JointAngleEditFieldValueChanged, true);
            app.JointAngleButton = uibutton(app.UIFigure, 'push');
            app.JointAngleButton.ButtonPushedFcn = createCallbackFcn(app, @JointAngleButtonPushed, true);
            app.JointAngleButton.Text = 'Joint Angle';
            app.JuiceButton = uibutton(app.UIFigure, 'push');
            app.JuiceButton.ButtonPushedFcn = createCallbackFcn(app, @JuiceButtonPushed, true);
            app.JuiceButton.Text = 'Juice';
            app.Switch_2 = uiswitch(app.UIFigure, 'slider');
            app.Switch_2.Items = {'Dobot', 'Kinova'};
            app.Switch_2.ValueChangedFcn = createCallbackFcn(app, @Switch_2ValueChanged, true);
            app.Switch_2.Value = 'Dobot';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = GUIv2

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end