classdef GUIv2 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                   matlab.ui.Figure
        Switch_2                   matlab.ui.control.Switch
        JuiceButton                matlab.ui.control.Button
        JointAngleButton           matlab.ui.control.Button
        JointAngleEditField        matlab.ui.control.NumericEditField
        JointAngleEditFieldLabel   matlab.ui.control.Label
        JointNumberEditField       matlab.ui.control.NumericEditField
        JointNumberEditFieldLabel  matlab.ui.control.Label
        CartesianButton            matlab.ui.control.Button
        ZEditField                 matlab.ui.control.NumericEditField
        ZEditFieldLabel            matlab.ui.control.Label
        YEditField                 matlab.ui.control.NumericEditField
        YEditFieldLabel            matlab.ui.control.Label
        XEditField                 matlab.ui.control.NumericEditField
        XEditFieldLabel            matlab.ui.control.Label
        Switch                     matlab.ui.control.Switch
        Lamp                       matlab.ui.control.Lamp
        RESUMEButton               matlab.ui.control.Button
        ESTOPButton                matlab.ui.control.Button
    end

    
    properties (Access = private)
        eStopActive = false;    % set initial estop state
        resumeCount = 0;        % inital press count

        robot = DobotMagician;

        zpos = 0;
        ypos = 0;
        xpos = 0;

        jointno = 0;
        jointangle = 0;

        DOF = 5;

    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: ESTOPButton
        function ESTOPButtonPushed(app, event)
            if app.eStopActive
                %resume logic
                app.resumeCount = app.resumeCount + 1;
                if app.resumeCount == 2
                    app.eStopActive = false;
                    app.resumeCount = 0;
                    app.Lamp.Color = "green";
                end
            else
                %E stop logic
                app.eStopActive = true;
                app.Lamp.Color = "red";

            end  
        end

        % Button pushed function: RESUMEButton
        function RESUMEButtonPushed(app, event)
            if app.eStopActive
                app.resumeCount = app.resumeCount + 1;
                if app.resumeCount == 2
                    app.eStopActive = false;
                    app.resumeCount = 0;
                    app.Lamp.Color = "green";
                end
            end
        end

        % Value changed function: Switch
        function SwitchValueChanged(app, event)
            value = app.Switch.Value;

            switch value
                case 'Teach'
                    % app.Lamp.Color = 'green';
                    switch app.Switch.Value
                        case 'Dobot'
                            clf;
                            app.robot = DobotMagician;
                        case 'Kinova'
                            clf;
                            app.robot = KinovaLink6;
                    end

                case 'Controller'
                    % app.Lamp.Color = 'red';
                    
                    id = 1; % Note: may need to be changed if multiple joysticks present
                    joy = vrjoystick(id);
                    caps(joy) % display joystick information

                    switch app.Switch_2.Value
                        case 'Dobot'
                            clf;
                            app.robot = DobotMagician;
                            app.DOF = 5;

                            q = zeros(1, app.DOF);  % Set initial robot configuration 'q'

                    HF = figure(1); % Initialise figure to display robot

                    app.robot.model.delay = 0.001;    % Set smaller delay when animating

                    duration = 300;  % Set duration of the simulation (seconds)
                    dt = 0.1;      % Set time step for simulation (seconds)
                    
                    n = 0;  % Initialise step count to zero 
                    tic;    % recording simulation start time
                    while( toc < duration)
                    
                        n=n+1; % increment step count
                    
                        % read joystick
                        [axes, buttons, povs] = read(joy);
                    
                        % -------------------------------------------------------------
                        % YOUR CODE GOES HERE
                        % 1 - turn joystick input into an end-effector velocity command
                        Kv = 0.2; % linear velocity gain
                        Kw = 1.0; % angular velocity gain
                    
                        vx = Kv*axes(2);
                        vy = Kv*axes(1);
                        vz = Kv*(buttons(5)-buttons(6));
                    
                        wx = Kw*axes(5);
                        wy = Kw*axes(4);
                        wz = Kw*axes(3);
                    
                        dx = [vx;vy;vz;wx;wy;wz]; % combined velocity vector
                    
                        % 2 - use J inverse to calculate joint velocity
                        J = app.robot.model.jacob0(q);
                        dq = pinv(J)*dx;
                    
                        % 3 - apply joint velocity to step robot joint angles 
                        q = q + dq'*dt;
                    
                        % -------------------------------------------------------------
                    
                        % Update plot
                        app.robot.model.animate(q);  
                    
                        % wait until loop time elapsed
                        if (toc > dt*n)
                            warning('Loop %i took too much time - consider increating dt',n);
                        end
                        while (toc < dt*n); % wait until loop time (dt) has elapsed 
                        end

                    end

                        case 'Kinova'
                            clf;
                            app.robot = KinovaLink6;
                            app.DOF = 6;

                            q = zeros(1, 6);  % Set initial robot configuration 'q'

                    HF = figure(1); % Initialise figure to display robot

                    app.robot.model.delay = 0.001;    % Set smaller delay when animating

                    duration = 300;  % Set duration of the simulation (seconds)
                    dt = 0.1;      % Set time step for simulation (seconds)
                    
                    n = 0;  % Initialise step count to zero 
                    tic;    % recording simulation start time
                    while( toc < duration)
                    
                        n=n+1; % increment step count
                    
                        % read joystick
                        [axes, buttons, povs] = read(joy);
                    
                        % -------------------------------------------------------------
                        % YOUR CODE GOES HERE
                        % 1 - turn joystick input into an end-effector velocity command
                        Kv = 0.2; % linear velocity gain
                        Kw = 1.0; % angular velocity gain
                    
                        vx = Kv*axes(2);
                        vy = Kv*axes(1);
                        vz = Kv*(buttons(5)-buttons(6));
                    
                        wx = Kw*axes(5);
                        wy = Kw*axes(4);
                        wz = Kw*axes(3);
                    
                        dx = [vx;vy;vz;wx;wy;wz]; % combined velocity vector
                    
                        % 2 - use J inverse to calculate joint velocity
                        J = app.robot.model.jacob0(q);
                        dq = pinv(J)*dx;
                    
                        % 3 - apply joint velocity to step robot joint angles 
                        q = q + dq'*dt;
                    
                        % -------------------------------------------------------------
                    
                        % Update plot
                        app.robot.model.animate(q);  
                    
                        % wait until loop time elapsed
                        if (toc > dt*n)
                            warning('Loop %i took too much time - consider increating dt',n);
                        end
                        while (toc < dt*n); % wait until loop time (dt) has elapsed 
                        end

                    end
                    end
            end
            
        end

        % Value changed function: Switch_2
        function Switch_2ValueChanged(app, event)
            value = app.Switch_2.Value;
            
            switch value
                case 'Dobot'
                    clf;
                    app.robot = DobotMagician;
                case 'Kinova'
                    clf;
                    app.robot = KinovaLink6;
            end
        end

        % Value changed function: XEditField
        function XEditFieldValueChanged(app, event)
            app.xpos = app.XEditField.Value;
        end

        % Value changed function: YEditField
        function YEditFieldValueChanged(app, event)
            app.ypos = app.YEditField.Value;
        end

        % Value changed function: ZEditField
        function ZEditFieldValueChanged(app, event)
            app.zpos = app.ZEditField.Value;
        end

        % Button pushed function: CartesianButton
        function CartesianButtonPushed(app, event)
                q0 = app.robot.model.getpos;
                newq = app.robot.model.ikcon(transl(app.xpos,app.ypos,app.zpos));

                qtraj = jtraj(q0, newq, 25);

                for i = 1:size(qtraj, 1)
                    app.robot.model.animate(qtraj(i,:));
                    drawnow();
                    pause(0);
                end
        end

        % Button pushed function: JointAngleButton
        function JointAngleButtonPushed(app, event)
            q0 = app.robot.model.getpos;
            new_angle = app.jointangle;
            q0(app.jointno) = new_angle;

            newq = q0; % Copy the initial joint configuration
            newq(app.jointno) = new_angle; % Update the first joint to the desired angle

            qtraj = jtraj(q0, newq, 25);

            for i = 1:size(qtraj, 1)
                % Animate the robot with the interpolated joint angles
                app.robot.model.animate(qtraj(i,:));
                drawnow();
                pause(0.1); % You can adjust the pause duration for animation speed
            end
        end

        % Value changed function: JointNumberEditField
        function JointNumberEditFieldValueChanged(app, event)
            app.jointno = app.JointNumberEditField.Value;
            
        end

        % Value changed function: JointAngleEditField
        function JointAngleEditFieldValueChanged(app, event)
            app.jointangle = app.JointAngleEditField.Value;
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.ESTOPButton = uibutton(app.UIFigure, 'push');
            app.ESTOPButton.ButtonPushedFcn = createCallbackFcn(app, @ESTOPButtonPushed, true);
            app.ESTOPButton.Text = 'E STOP';
            app.RESUMEButton = uibutton(app.UIFigure, 'push');
            app.RESUMEButton.ButtonPushedFcn = createCallbackFcn(app, @RESUMEButtonPushed, true);
            app.RESUMEButton.Text = 'RESUME';
            app.Lamp = uilamp(app.UIFigure);
            app.Switch = uiswitch(app.UIFigure, 'slider');
            app.Switch.Items = {'Teach', 'Controller'};
            app.Switch.ValueChangedFcn = createCallbackFcn(app, @SwitchValueChanged, true);
            app.Switch.Value = 'Teach';
            app.XEditFieldLabel = uilabel(app.UIFigure);
            app.XEditFieldLabel.Text = 'X';
            app.XEditField = uieditfield(app.UIFigure, 'numeric');
            app.XEditField.ValueChangedFcn = createCallbackFcn(app, @XEditFieldValueChanged, true);
            app.YEditFieldLabel = uilabel(app.UIFigure);
            app.YEditFieldLabel.Text = 'Y';
            app.YEditField = uieditfield(app.UIFigure, 'numeric');
            app.YEditField.ValueChangedFcn = createCallbackFcn(app, @YEditFieldValueChanged, true);
            app.ZEditFieldLabel = uilabel(app.UIFigure);
            app.ZEditFieldLabel.Text = 'Z';
            app.ZEditField = uieditfield(app.UIFigure, 'numeric');
            app.ZEditField.ValueChangedFcn = createCallbackFcn(app, @ZEditFieldValueChanged, true);
            app.CartesianButton = uibutton(app.UIFigure, 'push');
            app.CartesianButton.ButtonPushedFcn = createCallbackFcn(app, @CartesianButtonPushed, true);
            app.CartesianButton.Text = 'Cartesian';
            app.JointNumberEditFieldLabel = uilabel(app.UIFigure);
            app.JointNumberEditFieldLabel.Text = 'Joint Number';
            app.JointNumberEditField = uieditfield(app.UIFigure, 'numeric');
            app.JointNumberEditField.ValueChangedFcn = createCallbackFcn(app, @JointNumberEditFieldValueChanged, true);
            app.JointAngleEditFieldLabel = uilabel(app.UIFigure);
            app.JointAngleEditFieldLabel.Text = 'Joint Angle';
            app.JointAngleEditField = uieditfield(app.UIFigure, 'numeric');
            app.JointAngleEditField.ValueChangedFcn = createCallbackFcn(app, @JointAngleEditFieldValueChanged, true);
            app.JointAngleButton = uibutton(app.UIFigure, 'push');
            app.JointAngleButton.ButtonPushedFcn = createCallbackFcn(app, @JointAngleButtonPushed, true);
            app.JointAngleButton.Text = 'Joint Angle';
            app.JuiceButton = uibutton(app.UIFigure, 'push');
            app.JuiceButton.Text = 'Juice';
            app.Switch_2 = uiswitch(app.UIFigure, 'slider');
            app.Switch_2.Items = {'Dobot', 'Kinova'};
            app.Switch_2.ValueChangedFcn = createCallbackFcn(app, @Switch_2ValueChanged, true);
            app.Switch_2.Value = 'Dobot';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = GUIv2

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end