classdef eStop < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                   matlab.ui.Figure
        KinovaButton               matlab.ui.control.Button
        DobotButton                matlab.ui.control.Button
        ZEditField_2               matlab.ui.control.NumericEditField
        ZEditField_2Label          matlab.ui.control.Label
        YEditField_2               matlab.ui.control.NumericEditField
        YEditField_2Label          matlab.ui.control.Label
        XEditField_2               matlab.ui.control.NumericEditField
        XEditField_2Label          matlab.ui.control.Label
        q6EditField_2              matlab.ui.control.NumericEditField
        q6EditField_2Label         matlab.ui.control.Label
        q5EditField_2              matlab.ui.control.NumericEditField
        q5EditField_2Label         matlab.ui.control.Label
        q4EditField_2              matlab.ui.control.NumericEditField
        q4EditField_2Label         matlab.ui.control.Label
        q3EditField_2              matlab.ui.control.NumericEditField
        q3EditField_2Label         matlab.ui.control.Label
        q2EditField_2              matlab.ui.control.NumericEditField
        q2EditField_2Label         matlab.ui.control.Label
        q1EditField_2              matlab.ui.control.NumericEditField
        q1EditField_2Label         matlab.ui.control.Label
        JointMovementsLabel_2      matlab.ui.control.Label
        CartesianMovementsLabel_2  matlab.ui.control.Label
        Zplus_2                    matlab.ui.control.Button
        Zminus_2                   matlab.ui.control.Button
        Yplus_2                    matlab.ui.control.Button
        Yminus_2                   matlab.ui.control.Button
        Xplus_2                    matlab.ui.control.Button
        Xminus_2                   matlab.ui.control.Button
        q6plus_2                   matlab.ui.control.Button
        q6minus_2                  matlab.ui.control.Button
        q5plus_2                   matlab.ui.control.Button
        q5minus_2                  matlab.ui.control.Button
        q4plus_2                   matlab.ui.control.Button
        q4minus_2                  matlab.ui.control.Button
        q3plus_2                   matlab.ui.control.Button
        q3minus_2                  matlab.ui.control.Button
        q2plus_2                   matlab.ui.control.Button
        q2minus_2                  matlab.ui.control.Button
        q1plus_2                   matlab.ui.control.Button
        q1minus_2                  matlab.ui.control.Button
        JointMovementsLabel        matlab.ui.control.Label
        CartesianMovementsLabel    matlab.ui.control.Label
        JUICEButton                matlab.ui.control.Button
        ZEditField                 matlab.ui.control.NumericEditField
        ZEditFieldLabel            matlab.ui.control.Label
        YEditField                 matlab.ui.control.NumericEditField
        YEditFieldLabel            matlab.ui.control.Label
        XEditField                 matlab.ui.control.NumericEditField
        XEditFieldLabel            matlab.ui.control.Label
        Zplus                      matlab.ui.control.Button
        Zminus                     matlab.ui.control.Button
        Yplus                      matlab.ui.control.Button
        Yminus                     matlab.ui.control.Button
        Xplus                      matlab.ui.control.Button
        Xminus                     matlab.ui.control.Button
        q5plus                     matlab.ui.control.Button
        q5minus                    matlab.ui.control.Button
        q4plus                     matlab.ui.control.Button
        q4minus                    matlab.ui.control.Button
        q3plus                     matlab.ui.control.Button
        q3minus                    matlab.ui.control.Button
        q2plus                     matlab.ui.control.Button
        q2minus                    matlab.ui.control.Button
        q1plus                     matlab.ui.control.Button
        q1minus                    matlab.ui.control.Button
        q5EditField                matlab.ui.control.NumericEditField
        q5EditFieldLabel           matlab.ui.control.Label
        q4EditField                matlab.ui.control.NumericEditField
        q4EditFieldLabel           matlab.ui.control.Label
        q3EditField                matlab.ui.control.NumericEditField
        q3EditFieldLabel           matlab.ui.control.Label
        q2EditField                matlab.ui.control.NumericEditField
        q2EditFieldLabel           matlab.ui.control.Label
        q1EditField                matlab.ui.control.NumericEditField
        q1EditFieldLabel           matlab.ui.control.Label
        Lamp                       matlab.ui.control.Lamp
        RESButton                  matlab.ui.control.Button
        STOPButton                 matlab.ui.control.Button
    end

    
    properties (Access = private)
        eStopActive = false;
        resumeCount = 0;
        dobot = DobotMagician;
        
        dobot_q1 = 0;
        dobot_q2 = pi/4;
        dobot_q3 = pi/4;
        dobot_q4 = 0;
        dobot_q5 = 0;

        kinova_q1 = 0;
        kinova_q2 = 0;
        kinova_q3 = 0;
        kinova_q4 = 0;
        kinova_q5 = 0;
        kinova_q6 = 0;

    end
    
    methods (Access = public)
        
        function results = func(app)


        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: STOPButton
        function STOPButtonPushed(app, event)
            if app.eStopActive
                %resume logic
                app.resumeCount = app.resumeCount + 1;
                if app.resumeCount == 2
                    app.eStopActive = false;
                    app.resumeCount = 0;
                    app.Lamp.Enable = "on";
                end
            else
                %E stop logic
                app.eStopActive = true;
                app.Lamp.Enable = "off";

            end  
        end

        % Button pushed function: RESButton
        function RESButtonPushed(app, event)
            if app.eStopActive
                app.resumeCount = app.resumeCount + 1;
                if app.resumeCount == 2
                    app.eStopActive = false;
                    app.resumeCount = 0;
                    app.Lamp.Enable = "on";
                end
            end
        end

        % Button pushed function: q1minus
        function q1minusButtonPushed(app, event)
            q1_i = app.q1EditField.Value;
            q1_n = q1_i - 0.05;
            app.q1EditField.Value = q1_n;

            results;
        end

        % Button pushed function: q1plus
        function q1plusButtonPushed(app, event)
            q1_i = app.q1EditField.Value;
            q1_n = q1_i + 5;
            app.q1EditField.Value = q1_n;
        end

        % Button pushed function: q2minus
        function q2minusButtonPushed(app, event)
            q2_i = app.q2EditField.Value;
            q2_n = q2_i - 5;
            app.q2EditField.Value = q2_n;
        end

        % Button pushed function: q2plus
        function q2plusButtonPushed(app, event)
            q2_i = app.q2EditField.Value;
            q2_n = q2_i + 5;
            app.q2EditField.Value = q2_n;
        end

        % Button pushed function: q3minus
        function q3minusButtonPushed(app, event)
            qi = app.q3EditField.Value;
            qn = qi - 5;
            app.q3EditField.Value = qn;
        end

        % Button pushed function: q3plus
        function q3plusButtonPushed(app, event)
            qi = app.q3EditField.Value;
            qn = qi + 0.05;
            app.q3EditField.Value = qn;
        end

        % Button pushed function: q4minus
        function q4minusButtonPushed(app, event)
            qi = app.q4EditField.Value;
            qn = qi - 0.05;
            app.q4EditField.Value = qn;
        end

        % Button pushed function: q4plus
        function q4plusButtonPushed(app, event)
            qi = app.q4EditField.Value;
            qn = qi + 0.05;
            app.q4EditField.Value = qn;
        end

        % Button pushed function: q5minus
        function q5minusButtonPushed(app, event)
            qi = app.q5EditField.Value;
            qn = qi - 0.05;
            app.q5EditField.Value = qn;
        end

        % Button pushed function: q5plus
        function q5plusButtonPushed(app, event)
            qi = app.q5EditField.Value;
            qn = qi + 0.05;
            app.q5EditField.Value = qn;
        end

        % Button pushed function: Xminus
        function XminusButtonPushed(app, event)
            qi = app.XEditField.Value;
            qn = qi - 0.05;
            app.XEditField.Value = qn;
        end

        % Button pushed function: Xplus
        function XplusButtonPushed(app, event)
            qi = app.XEditField.Value;
            qn = qi + 0.05;
            app.XEditField.Value = qn;
        end

        % Button pushed function: Yminus
        function YminusButtonPushed(app, event)
            qi = app.YEditField.Value;
            qn = qi - 0.05;
            app.YEditField.Value = qn;
        end

        % Button pushed function: Yplus
        function YplusButtonPushed(app, event)
            qi = app.YEditField.Value;
            qn = qi + 0.05;
            app.YEditField.Value = qn;
        end

        % Button pushed function: Zminus
        function ZminusButtonPushed(app, event)
            qi = app.ZEditField.Value;
            qn = qi - 0.05;
            app.ZEditField.Value = qn;
        end

        % Button pushed function: Zplus
        function ZplusButtonPushed(app, event)
            qi = app.ZEditField.Value;
            qn = qi + 0.05;
            app.ZEditField.Value = qn;
        end

        % Button pushed function: q1minus_2
        function q1minus_2ButtonPushed(app, event)
            qi = app.q1EditField_2.Value;
            qn = qi - 0.05;
            app.q1EditField_2.Value = qn;
        end

        % Button pushed function: q1plus_2
        function q1plus_2ButtonPushed(app, event)
            qi = app.q1EditField_2.Value;
            qn = qi + 0.05;
            app.q1EditField_2.Value = qn;
        end

        % Button pushed function: q2minus_2
        function q2minus_2ButtonPushed(app, event)
            qi = app.q2EditField_2.Value;
            qn = qi - 0.05;
            app.q2EditField_2.Value = qn;
        end

        % Button pushed function: q2plus_2
        function q2plus_2ButtonPushed(app, event)
            qi = app.q2EditField_2.Value;
            qn = qi + 0.05;
            app.q2EditField_2.Value = qn;
        end

        % Button pushed function: q3minus_2
        function q3minus_2ButtonPushed(app, event)
            qi = app.q3EditField_2.Value;
            qn = qi - 0.05;
            app.q3EditField_2.Value = qn;
        end

        % Button pushed function: q3plus_2
        function q3plus_2ButtonPushed(app, event)
            qi = app.q3EditField_2.Value;
            qn = qi + 0.05;
            app.q3EditField_2.Value = qn;
        end

        % Button pushed function: q4minus_2
        function q4minus_2ButtonPushed(app, event)
            qi = app.q4EditField_2.Value;
            qn = qi - 0.05;
            app.q4EditField_2.Value = qn;
        end

        % Button pushed function: q4plus_2
        function q4plus_2ButtonPushed(app, event)
            qi = app.q4EditField_2.Value;
            qn = qi + 0.05;
            app.q4EditField_2.Value = qn;
        end

        % Button pushed function: q5minus_2
        function q5minus_2ButtonPushed(app, event)
            qi = app.q5EditField_2.Value;
            qn = qi - 0.05;
            app.q5EditField_2.Value = qn;
        end

        % Button pushed function: q5plus_2
        function q5plus_2ButtonPushed(app, event)
            qi = app.q5EditField_2.Value;
            qn = qi + 0.05;
            app.q5EditField_2.Value = qn;
        end

        % Button pushed function: q6minus_2
        function q6minus_2ButtonPushed(app, event)
            qi = app.q6EditField_2.Value;
            qn = qi - 0.05;
            app.q6EditField_2.Value = qn;
        end

        % Button pushed function: q6plus_2
        function q6plus_2ButtonPushed(app, event)
            qi = app.q6EditField_2.Value;
            qn = qi + 0.05;
            app.q6EditField_2.Value = qn;
        end

        % Button pushed function: Xminus_2
        function Xminus_2ButtonPushed(app, event)
            qi = app.XEditField_2.Value;
            qn = qi - 0.05;
            app.XEditField_2.Value = qn;
        end

        % Button pushed function: Xplus_2
        function Xplus_2ButtonPushed(app, event)
            qi = app.XEditField_2.Value;
            qn = qi + 0.05;
            app.XEditField_2.Value = qn;
        end

        % Button pushed function: Yminus_2
        function Yminus_2ButtonPushed(app, event)
            qi = app.YEditField_2.Value;
            qn = qi - 0.05;
            app.YEditField_2.Value = qn;
        end

        % Button pushed function: Yplus_2
        function Yplus_2ButtonPushed(app, event)
            qi = app.YEditField_2.Value;
            qn = qi + 0.05;
            app.YEditField_2.Value = qn;
        end

        % Button pushed function: Zminus_2
        function Zminus_2ButtonPushed(app, event)
            qi = app.ZEditField_2.Value;
            qn = qi - 0.05;
            app.ZEditField_2.Value = qn;
        end

        % Button pushed function: Zplus_2
        function Zplus_2ButtonPushed(app, event)
            qi = app.ZEditField_2.Value;
            qn = qi + 0.05;
            app.ZEditField_2.Value = qn;
        end

        % Button pushed function: DobotButton
        function DobotButtonPushed(app, event)
            dq1 = app.q1EditField.Value;
            dq2 = app.q2EditField.Value;
            dq3 = app.q3EditField.Value;
            dq4 = app.q4EditField.Value;
            dq5 = app.q5EditField.Value;

            q0 = app.dobot.model.getpos;
            q1 = [dq1,dq2,dq3,dq4,dq5];

            qtraj1 = jtraj(q0,q1,25);

            for i = 1:size(qtraj1,1)
                app.dobot.model.animate(qtraj1(1,:));
                drawnow();
                pause(0);
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.STOPButton = uibutton(app.UIFigure, 'push');
            app.STOPButton.ButtonPushedFcn = createCallbackFcn(app, @STOPButtonPushed, true);
            app.STOPButton.Text = 'STOP';
            app.RESButton = uibutton(app.UIFigure, 'push');
            app.RESButton.ButtonPushedFcn = createCallbackFcn(app, @RESButtonPushed, true);
            app.RESButton.Text = 'RES';
            app.Lamp = uilamp(app.UIFigure);
            app.q1EditFieldLabel = uilabel(app.UIFigure);
            app.q1EditFieldLabel.Text = 'q1';
            app.q1EditField = uieditfield(app.UIFigure, 'numeric');
            app.q2EditFieldLabel = uilabel(app.UIFigure);
            app.q2EditFieldLabel.Text = 'q2';
            app.q2EditField = uieditfield(app.UIFigure, 'numeric');
            app.q3EditFieldLabel = uilabel(app.UIFigure);
            app.q3EditFieldLabel.Text = 'q3';
            app.q3EditField = uieditfield(app.UIFigure, 'numeric');
            app.q4EditFieldLabel = uilabel(app.UIFigure);
            app.q4EditFieldLabel.Text = 'q4';
            app.q4EditField = uieditfield(app.UIFigure, 'numeric');
            app.q5EditFieldLabel = uilabel(app.UIFigure);
            app.q5EditFieldLabel.Text = 'q5';
            app.q5EditField = uieditfield(app.UIFigure, 'numeric');
            app.q1minus = uibutton(app.UIFigure, 'push');
            app.q1minus.ButtonPushedFcn = createCallbackFcn(app, @q1minusButtonPushed, true);
            app.q1minus.Text = '-';
            app.q1plus = uibutton(app.UIFigure, 'push');
            app.q1plus.ButtonPushedFcn = createCallbackFcn(app, @q1plusButtonPushed, true);
            app.q1plus.Text = '+';
            app.q2minus = uibutton(app.UIFigure, 'push');
            app.q2minus.ButtonPushedFcn = createCallbackFcn(app, @q2minusButtonPushed, true);
            app.q2minus.Text = '-';
            app.q2plus = uibutton(app.UIFigure, 'push');
            app.q2plus.ButtonPushedFcn = createCallbackFcn(app, @q2plusButtonPushed, true);
            app.q2plus.Text = '+';
            app.q3minus = uibutton(app.UIFigure, 'push');
            app.q3minus.ButtonPushedFcn = createCallbackFcn(app, @q3minusButtonPushed, true);
            app.q3minus.Text = '-';
            app.q3plus = uibutton(app.UIFigure, 'push');
            app.q3plus.ButtonPushedFcn = createCallbackFcn(app, @q3plusButtonPushed, true);
            app.q3plus.Text = '+';
            app.q4minus = uibutton(app.UIFigure, 'push');
            app.q4minus.ButtonPushedFcn = createCallbackFcn(app, @q4minusButtonPushed, true);
            app.q4minus.Text = '-';
            app.q4plus = uibutton(app.UIFigure, 'push');
            app.q4plus.ButtonPushedFcn = createCallbackFcn(app, @q4plusButtonPushed, true);
            app.q4plus.Text = '+';
            app.q5minus = uibutton(app.UIFigure, 'push');
            app.q5minus.ButtonPushedFcn = createCallbackFcn(app, @q5minusButtonPushed, true);
            app.q5minus.Text = '-';
            app.q5plus = uibutton(app.UIFigure, 'push');
            app.q5plus.ButtonPushedFcn = createCallbackFcn(app, @q5plusButtonPushed, true);
            app.q5plus.Text = '+';
            app.Xminus = uibutton(app.UIFigure, 'push');
            app.Xminus.ButtonPushedFcn = createCallbackFcn(app, @XminusButtonPushed, true);
            app.Xminus.Text = '-';
            app.Xplus = uibutton(app.UIFigure, 'push');
            app.Xplus.ButtonPushedFcn = createCallbackFcn(app, @XplusButtonPushed, true);
            app.Xplus.Text = '+';
            app.Yminus = uibutton(app.UIFigure, 'push');
            app.Yminus.ButtonPushedFcn = createCallbackFcn(app, @YminusButtonPushed, true);
            app.Yminus.Text = '-';
            app.Yplus = uibutton(app.UIFigure, 'push');
            app.Yplus.ButtonPushedFcn = createCallbackFcn(app, @YplusButtonPushed, true);
            app.Yplus.Text = '+';
            app.Zminus = uibutton(app.UIFigure, 'push');
            app.Zminus.ButtonPushedFcn = createCallbackFcn(app, @ZminusButtonPushed, true);
            app.Zminus.Text = '-';
            app.Zplus = uibutton(app.UIFigure, 'push');
            app.Zplus.ButtonPushedFcn = createCallbackFcn(app, @ZplusButtonPushed, true);
            app.Zplus.Text = '+';
            app.XEditFieldLabel = uilabel(app.UIFigure);
            app.XEditFieldLabel.Text = 'X';
            app.XEditField = uieditfield(app.UIFigure, 'numeric');
            app.YEditFieldLabel = uilabel(app.UIFigure);
            app.YEditFieldLabel.Text = 'Y';
            app.YEditField = uieditfield(app.UIFigure, 'numeric');
            app.ZEditFieldLabel = uilabel(app.UIFigure);
            app.ZEditFieldLabel.Text = 'Z';
            app.ZEditField = uieditfield(app.UIFigure, 'numeric');
            app.JUICEButton = uibutton(app.UIFigure, 'push');
            app.JUICEButton.Text = 'JUICE';
            app.CartesianMovementsLabel = uilabel(app.UIFigure);
            app.CartesianMovementsLabel.Text = 'Cartesian Movements';
            app.JointMovementsLabel = uilabel(app.UIFigure);
            app.JointMovementsLabel.Text = 'Joint Movements';
            app.q1minus_2 = uibutton(app.UIFigure, 'push');
            app.q1minus_2.ButtonPushedFcn = createCallbackFcn(app, @q1minus_2ButtonPushed, true);
            app.q1minus_2.Text = '-';
            app.q1plus_2 = uibutton(app.UIFigure, 'push');
            app.q1plus_2.ButtonPushedFcn = createCallbackFcn(app, @q1plus_2ButtonPushed, true);
            app.q1plus_2.Text = '+';
            app.q2minus_2 = uibutton(app.UIFigure, 'push');
            app.q2minus_2.ButtonPushedFcn = createCallbackFcn(app, @q2minus_2ButtonPushed, true);
            app.q2minus_2.Text = '-';
            app.q2plus_2 = uibutton(app.UIFigure, 'push');
            app.q2plus_2.ButtonPushedFcn = createCallbackFcn(app, @q2plus_2ButtonPushed, true);
            app.q2plus_2.Text = '+';
            app.q3minus_2 = uibutton(app.UIFigure, 'push');
            app.q3minus_2.ButtonPushedFcn = createCallbackFcn(app, @q3minus_2ButtonPushed, true);
            app.q3minus_2.Text = '-';
            app.q3plus_2 = uibutton(app.UIFigure, 'push');
            app.q3plus_2.ButtonPushedFcn = createCallbackFcn(app, @q3plus_2ButtonPushed, true);
            app.q3plus_2.Text = '+';
            app.q4minus_2 = uibutton(app.UIFigure, 'push');
            app.q4minus_2.ButtonPushedFcn = createCallbackFcn(app, @q4minus_2ButtonPushed, true);
            app.q4minus_2.Text = '-';
            app.q4plus_2 = uibutton(app.UIFigure, 'push');
            app.q4plus_2.ButtonPushedFcn = createCallbackFcn(app, @q4plus_2ButtonPushed, true);
            app.q4plus_2.Text = '+';
            app.q5minus_2 = uibutton(app.UIFigure, 'push');
            app.q5minus_2.ButtonPushedFcn = createCallbackFcn(app, @q5minus_2ButtonPushed, true);
            app.q5minus_2.Text = '-';
            app.q5plus_2 = uibutton(app.UIFigure, 'push');
            app.q5plus_2.ButtonPushedFcn = createCallbackFcn(app, @q5plus_2ButtonPushed, true);
            app.q5plus_2.Text = '+';
            app.q6minus_2 = uibutton(app.UIFigure, 'push');
            app.q6minus_2.ButtonPushedFcn = createCallbackFcn(app, @q6minus_2ButtonPushed, true);
            app.q6minus_2.Text = '-';
            app.q6plus_2 = uibutton(app.UIFigure, 'push');
            app.q6plus_2.ButtonPushedFcn = createCallbackFcn(app, @q6plus_2ButtonPushed, true);
            app.q6plus_2.Text = '+';
            app.Xminus_2 = uibutton(app.UIFigure, 'push');
            app.Xminus_2.ButtonPushedFcn = createCallbackFcn(app, @Xminus_2ButtonPushed, true);
            app.Xminus_2.Text = '-';
            app.Xplus_2 = uibutton(app.UIFigure, 'push');
            app.Xplus_2.ButtonPushedFcn = createCallbackFcn(app, @Xplus_2ButtonPushed, true);
            app.Xplus_2.Text = '+';
            app.Yminus_2 = uibutton(app.UIFigure, 'push');
            app.Yminus_2.ButtonPushedFcn = createCallbackFcn(app, @Yminus_2ButtonPushed, true);
            app.Yminus_2.Text = '-';
            app.Yplus_2 = uibutton(app.UIFigure, 'push');
            app.Yplus_2.ButtonPushedFcn = createCallbackFcn(app, @Yplus_2ButtonPushed, true);
            app.Yplus_2.Text = '+';
            app.Zminus_2 = uibutton(app.UIFigure, 'push');
            app.Zminus_2.ButtonPushedFcn = createCallbackFcn(app, @Zminus_2ButtonPushed, true);
            app.Zminus_2.Text = '-';
            app.Zplus_2 = uibutton(app.UIFigure, 'push');
            app.Zplus_2.ButtonPushedFcn = createCallbackFcn(app, @Zplus_2ButtonPushed, true);
            app.Zplus_2.Text = '+';
            app.CartesianMovementsLabel_2 = uilabel(app.UIFigure);
            app.CartesianMovementsLabel_2.Text = 'Cartesian Movements';
            app.JointMovementsLabel_2 = uilabel(app.UIFigure);
            app.JointMovementsLabel_2.Text = 'Joint Movements';
            app.q1EditField_2Label = uilabel(app.UIFigure);
            app.q1EditField_2Label.Text = 'q1';
            app.q1EditField_2 = uieditfield(app.UIFigure, 'numeric');
            app.q2EditField_2Label = uilabel(app.UIFigure);
            app.q2EditField_2Label.Text = 'q2';
            app.q2EditField_2 = uieditfield(app.UIFigure, 'numeric');
            app.q3EditField_2Label = uilabel(app.UIFigure);
            app.q3EditField_2Label.Text = 'q3';
            app.q3EditField_2 = uieditfield(app.UIFigure, 'numeric');
            app.q4EditField_2Label = uilabel(app.UIFigure);
            app.q4EditField_2Label.Text = 'q4';
            app.q4EditField_2 = uieditfield(app.UIFigure, 'numeric');
            app.q5EditField_2Label = uilabel(app.UIFigure);
            app.q5EditField_2Label.Text = 'q5';
            app.q5EditField_2 = uieditfield(app.UIFigure, 'numeric');
            app.q6EditField_2Label = uilabel(app.UIFigure);
            app.q6EditField_2Label.Text = 'q6';
            app.q6EditField_2 = uieditfield(app.UIFigure, 'numeric');
            app.XEditField_2Label = uilabel(app.UIFigure);
            app.XEditField_2Label.Text = 'X';
            app.XEditField_2 = uieditfield(app.UIFigure, 'numeric');
            app.YEditField_2Label = uilabel(app.UIFigure);
            app.YEditField_2Label.Text = 'Y';
            app.YEditField_2 = uieditfield(app.UIFigure, 'numeric');
            app.ZEditField_2Label = uilabel(app.UIFigure);
            app.ZEditField_2Label.Text = 'Z';
            app.ZEditField_2 = uieditfield(app.UIFigure, 'numeric');
            app.DobotButton = uibutton(app.UIFigure, 'push');
            app.DobotButton.ButtonPushedFcn = createCallbackFcn(app, @DobotButtonPushed, true);
            app.DobotButton.Text = 'Dobot';
            app.KinovaButton = uibutton(app.UIFigure, 'push');
            app.KinovaButton.Text = 'Kinova';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = eStop

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end